classdef paramAdjustApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        RecalculatecoefficientsLabel  matlab.ui.control.Label
        mousebuttonLabel              matlab.ui.control.Label
        loationthenreleasingtheLabel  matlab.ui.control.Label
        draggingittothedesiredLabel   matlab.ui.control.Label
        andholdingonthelineLabel      matlab.ui.control.Label
        AdjustcurvesbyclickingLabel   matlab.ui.control.Label
        invertgraphCheckBox           matlab.ui.control.CheckBox
        SettouseButton                matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        coef_X5EditField              matlab.ui.control.NumericEditField
        coef_X5EditFieldLabel         matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        ExportButton                  matlab.ui.control.Button
        coef_X4EditField              matlab.ui.control.NumericEditField
        coef_X4EditFieldLabel         matlab.ui.control.Label
        MeasurementsPanel             matlab.ui.container.Panel
        MinY_XcmEditField             matlab.ui.control.NumericEditField
        MinY_XLabel                   matlab.ui.control.Label
        MaxY_XcmEditField             matlab.ui.control.NumericEditField
        MaxY_XLabel                   matlab.ui.control.Label
        RButton                       matlab.ui.control.Button
        LButton                       matlab.ui.control.Button
        TraceYEditField               matlab.ui.control.NumericEditField
        TraceYEditFieldLabel          matlab.ui.control.Label
        TraceXcmEditField             matlab.ui.control.NumericEditField
        TraceXcmEditFieldLabel        matlab.ui.control.Label
        MinYEditField                 matlab.ui.control.NumericEditField
        MinYEditFieldLabel            matlab.ui.control.Label
        MaxYEditField                 matlab.ui.control.NumericEditField
        MaxYEditFieldLabel            matlab.ui.control.Label
        coef_X3EditField              matlab.ui.control.NumericEditField
        coef_X3EditFieldLabel         matlab.ui.control.Label
        coef_X2EditField              matlab.ui.control.NumericEditField
        coef_X2EditFieldLabel         matlab.ui.control.Label
        coef_X1EditField              matlab.ui.control.NumericEditField
        coef_X1EditFieldLabel         matlab.ui.control.Label
        coef_X0EditField              matlab.ui.control.NumericEditField
        coef_X0EditFieldLabel         matlab.ui.control.Label
        maxxcmEditField               matlab.ui.control.NumericEditField
        maxxcmEditFieldLabel          matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        orig_coeffx % original coeff when app started
        temp_coeffx % coeff used in trial plots, not yet used
        coeffx % Coeff used in measurements, to be exported
        xx % xmax/range in cm
        traceX % x value for the trace window
        paramtype % 1=Bl 2=Kd 3=Ld
        orig_xmax % original range used in plots
        invert_graph % invert the y axis of temp plot
        graphTitle % Description
        graphYlabel % Description
        m_location % mouse location
        buttonDownFlag % Description
    end

    properties (Access = public)
        xmax     %
        mainapp  % handle from main app
    end

    methods (Access = private)

        function results = updateFields(app)
            myxx = app.xmax;
            app.maxxcmEditField.Value = myxx;

            % update display of coef
            app.coef_X0EditField.Value = app.coeffx(6);
            app.coef_X1EditField.Value = app.coeffx(5);
            app.coef_X2EditField.Value = app.coeffx(4);
            app.coef_X3EditField.Value = app.coeffx(3);
            app.coef_X4EditField.Value = app.coeffx(2);
            app.coef_X5EditField.Value = app.coeffx(1);

            % put values into ymax, ymin, xx
            mycoeff = app.coeffx;
            myxx = myxx/100;
            xxx = -myxx: myxx/10: myxx;
            app.xx = xxx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            [maxy, mxidx] = max(yyy);
            [miny, mnidx] = min(yyy);
            app.MaxYEditField.Value = maxy;
            app.MaxY_XcmEditField.Value = xcm(mxidx);
            app.MinYEditField.Value = miny;
            app.MinY_XcmEditField.Value = xcm(mnidx);

            % put values into traceX
            app.traceX = 0;
            app.TraceYEditField.Value = polyval(mycoeff, 0);

            results = 1;
        end


        % use temp_coeffx to re-plot the line
        function results = temp_updateFields(app)
            myxx = app.xmax;

            % put values into ymax, ymin, xx
            mycoeff = app.temp_coeffx;
            myxx = myxx/100;
            xxx = -myxx: myxx/10: myxx;
            app.xx = xxx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);

            % plot graph
            results = 1;
            results = plotGraph(app, xcm, yyy, results);
            app.UIAxes.Children.ButtonDownFcn = @(src,evt)app.buttonDownfcn(src, evt);
        end

        function Get_Mouse_Location(app, ~, ~ )
            %   This function gets the location of the mouse pointer
            app.m_location = get(gca, 'CurrentPoint');
        end

        function results = motionmovefcn(app,src,evnt)
            results = 1;

            if app.buttonDownFlag == 1

                % Get the current mouse position
                mousePos = get(app.UIAxes, 'CurrentPoint');
                xMouse = mousePos(1, 1); % X coordinate of the mouse
                yMouse = mousePos(1, 2); % Y coordinate of the mouse

                % Update the xdata and ydata of the line object with the mouse position
                lineObj = app.UIAxes.Children; % Get the line objects in the axes
                if ~isempty(lineObj)

                    % Find the closest point to (xMouse, yMouse)
                    xData = lineObj.XData;

                    yData = lineObj.YData;
                    if xMouse > 0
                        xidx = xData >= 0;
                        adv = length(xData) - sum(xidx);
                    else
                        xidx = xData <0;
                        adv = 0;
                    end

                    % limit mouse not to move beyond xmax
                    xMouseData = xMouse;
                    if xMouseData > app.xmax
                        xMouseData = app.xmax;
                    elseif xMouseData < -app.xmax
                        xMouseData = -app.xmax;
                    end

                    distances = sqrt((xData(xidx) - xMouseData).^2 + (yData(xidx) - yMouse).^2);
                    %distances = (xData - xMouse);
                    [~, closestIdx] = min(distances);

                    % Replace the closest point with (xMouse, yMouse)
                    lineObj.XData(closestIdx+adv) = xMouseData;
                    lineObj.YData(closestIdx+adv) = yMouse;
                    %          drawnow; % Ensure the plot updates immediately
                end
                % plot graph
                results = 1;

            end


        end
        function results = buttonDownfcn(app,src,evnt)
            results = 1;
            app.buttonDownFlag = 1;
        end

        function results = buttonUpfcn(app,src,evnt)
            results = 1;
            app.buttonDownFlag = 0;
        end

        function results = plotGraph(app, xcm, yyy, resultsIn)

            results = resultsIn;
            cla(app.UIAxes);

            if app.invert_graph==1
                temp = 1./yyy;
                line(app.UIAxes, xcm, temp, 'Color', 'green');
                title(app.UIAxes, 'inverted')
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'position (cm)');
                ylabel(app.UIAxes, ' ' );
            else
                line(app.UIAxes, xcm, yyy, 'Color', 'green');
                title(app.UIAxes, app.graphTitle)
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'position (cm)');
                ylabel(app.UIAxes, app.graphYlabel );
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, xx, coeff, paramtype, strTitle, strylabel, mainapp)
            %disp('got data')
            app.mainapp = mainapp;
            app.paramtype = paramtype;

            % put value into xmax
            app.xmax = xx*100;
            app.orig_xmax = xx*100;
            app.maxxcmEditField.Value = xx*100;

            % put values into coef
            app.coeffx = zeros(1,6);

            if length(coeff) == 5
                app.coef_X0EditField.Value = coeff(1);
                app.coeffx(5) = coeff(1);
                app.coef_X1EditField.Value = coeff(2);
                app.coeffx(4) = coeff(2);
                app.coef_X2EditField.Value = coeff(3);
                app.coeffx(3) = coeff(3);
                app.coef_X3EditField.Value = coeff(4);
                app.coeffx(2) = coeff(4);
                app.coef_X4EditField.Value = coeff(5);
                app.coeffx(1) = coeff(5);
                app.coef_X5EditField.Value = 0;
            else
                app.coef_X0EditField.Value = coeff(1);
                app.coeffx(6) = coeff(1);
                app.coef_X1EditField.Value = coeff(2);
                app.coeffx(5) = coeff(2);
                app.coef_X2EditField.Value = coeff(3);
                app.coeffx(4) = coeff(3);
                app.coef_X3EditField.Value = coeff(4);
                app.coeffx(3) = coeff(4);
                app.coef_X4EditField.Value = coeff(5);
                app.coeffx(2) = coeff(5);
                app.coef_X5EditField.Value = coeff(6);
                app.coeffx(1) = coeff(6);
            end

            app.orig_coeffx = app.coeffx;
            app.temp_coeffx = app.coeffx;

            if paramtype ==5  % 5 = rmvv

                % put values into ymax, ymin, xx
                mycoeff = app.coeffx;
                xxx = -xx/10: xx/100: xx/10;
                app.xx = xxx;
                xcm = xxx*100;
                yyy = polyval(mycoeff, xxx);
                [maxy, mxidx] = max(yyy);
                [miny, mnidx] = min(yyy);
                % app.MaxYEditField.Value = maxy;
                app.MaxY_XcmEditField.Value = xcm(mxidx);
                app.MinYEditField.Value = miny;
                app.MinY_XcmEditField.Value = xcm(mnidx);

                % put values into traceX
                app.traceX = 0;
                app.TraceYEditField.Value = polyval(mycoeff, 0);

                % remember graph title and ylabel
                app.graphYlabel = strylabel;
                app.graphTitle = strTitle;

                % plot graph
                line(app.UIAxes, xcm, yyy);
                title(app.UIAxes, strTitle)
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'velocity (cm/s)');
                ylabel(app.UIAxes, strylabel );

                app.UIAxes.Children.ButtonDownFcn = @(src,evt)app.buttonDownfcn(src, evt);
                app.UIFigure.WindowButtonMotionFcn = @(src,evt)app.motionmovefcn(src, evt);
                app.UIFigure.WindowButtonUpFcn = @(src,evt)app.buttonUpfcn(src, evt);

                % default invert to zero
                app.invert_graph = 0;
            else

                % put values into ymax, ymin, xx
                mycoeff = app.coeffx;

                xxx = -xx: xx/10: xx;
                app.xx = xxx;
                xcm = xxx*100;
                yyy = polyval(mycoeff, xxx);
                [maxy, mxidx] = max(yyy);
                [miny, mnidx] = min(yyy);
                % app.MaxYEditField.Value = maxy;
                app.MaxY_XcmEditField.Value = xcm(mxidx);
                app.MinYEditField.Value = miny;
                app.MinY_XcmEditField.Value = xcm(mnidx);

                % put values into traceX
                app.traceX = 0;
                app.TraceYEditField.Value = polyval(mycoeff, 0);

                % remember graph title and ylabel
                app.graphYlabel = strylabel;
                app.graphTitle = strTitle;

                % plot graph
                line(app.UIAxes, xcm, yyy);
                title(app.UIAxes, strTitle)
                grid(app.UIAxes, 'on')
                xlabel(app.UIAxes, 'position (cm)');
                ylabel(app.UIAxes, strylabel );

                app.UIAxes.Children.ButtonDownFcn = @(src,evt)app.buttonDownfcn(src, evt);
                app.UIFigure.WindowButtonMotionFcn = @(src,evt)app.motionmovefcn(src, evt);
                app.UIFigure.WindowButtonUpFcn = @(src,evt)app.buttonUpfcn(src, evt);

                % default invert to zero
                app.invert_graph = 0;

            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % put value into xmax
            myxx = app.orig_xmax;
            app.xmax = myxx;

            % put values into coef
            app.temp_coeffx = app.orig_coeffx;
            app.coeffx = app.orig_coeffx;

            % reset invert button
            app.invert_graph = 0;
            app.invertgraphCheckBox.Value = 0;

            updateFields(app);
            temp_updateFields(app);

        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            lstate = app.mainapp.state;

            if app.paramtype == 1
                Blxx = fliplr(app.coeffx);

                lstate.elec.Bl = Blxx(1);
                app.mainapp.setSysParam(lstate);
                app.mainapp.processNewBlxx(Blxx);

            end
            if app.paramtype == 2
                Kdxx = fliplr(app.coeffx);

                lstate.mech.cm = 1/Kdxx(1);
                app.mainapp.setSysParam(lstate);
                app.mainapp.processNewKdxx(Kdxx);
            end
            if app.paramtype == 3
                Ldxx = fliplr(app.coeffx);

                lstate.elec.Le = Ldxx(1);
                app.mainapp.setSysParam(lstate);
                app.mainapp.processNewLdxx(Ldxx);
            end
            if app.paramtype == 4
                Rmxx = fliplr(app.coeffx);

                lstate.mech.rm = Rmxx(1);
                app.mainapp.setSysParam(lstate);
                app.mainapp.processNewRmxx(Rmxx);
                Rmvv = app.mainapp.Rmvv_orig;
                Rmvv(1) = Rmxx(1);
                app.mainapp.processNewRmvv(Rmvv);
            end
            if app.paramtype == 5
                Rmvv = fliplr(app.coeffx);

                lstate.mech.rm = Rmvv(1);
                app.mainapp.setSysParam(lstate);
                app.mainapp.processNewRmvv(Rmvv);
                Rmxx = app.mainapp.Rmxx_orig;
                Rmxx(1) = Rmvv(1);
                app.mainapp.processNewRmxx(Rmxx);
            end

        end

        % Value changed function: maxxcmEditField
        function maxxcmEditFieldValueChanged(app, event)
            value = app.maxxcmEditField.Value;
            app.xmax = value;
            updateFields(app);
        end

        % Value changed function: coef_X0EditField
        function coef_X0EditFieldValueChanged(app, event)
            value = app.coef_X0EditField.Value;
            app.coeffx(6) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Value changed function: coef_X1EditField
        function coef_X1EditFieldValueChanged(app, event)
            value = app.coef_X1EditField.Value;
            app.coeffx(5) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Value changed function: coef_X2EditField
        function coef_X2EditFieldValueChanged(app, event)
            value = app.coef_X2EditField.Value;
            app.coeffx(4) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Value changed function: coef_X3EditField
        function coef_X3EditFieldValueChanged(app, event)
            value = app.coef_X3EditField.Value;
            app.coeffx(3) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            myxx = app.traceX;
            myxx = myxx - 0.0001;

            if myxx < -(app.xmax)
                myxx = -(app.xmax);
            end
            app.traceX = myxx;
            app.TraceXcmEditField.Value = myxx*100;
            mycoeff = app.coeffx;
            app.TraceYEditField.Value = polyval(mycoeff, myxx);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            myxx = app.traceX;
            myxx = myxx + 0.0001;

            if myxx > (app.xmax)
                myxx = app.xmax;
            end
            app.traceX = myxx;
            app.TraceXcmEditField.Value = myxx*100;
            mycoeff = app.coeffx;
            app.TraceYEditField.Value = polyval(mycoeff, myxx);
        end

        % Value changed function: coef_X4EditField
        function coef_X4EditFieldValueChanged(app, event)
            value = app.coef_X4EditField.Value;
            app.coeffx(2) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Value changed function: TraceXcmEditField
        function TraceXcmEditFieldValueChanged(app, event)
            value = app.TraceXcmEditField.Value;
            value = value/100;
            app.traceX = value;
            Blxx = app.coeffx;
            Byy = polyval(Blxx, value);
            app.TraceYEditField.Value = Byy;
        end

        % Button pushed function: SettouseButton
        function SettouseButtonPushed(app, event)

            if app.invert_graph == 0
                lineObj = app.UIAxes.Children; % Get the line objects in the axes
                if ~isempty(lineObj)

                    % calculate new polynomials
                    xData = (lineObj.XData)/100;
                    yData = lineObj.YData;
                    [sp] = polyfit(xData, yData, 5);
                    app.temp_coeffx = sp;

                    % plot new curve with the polynomials
                    yyy = polyval(sp, xData);
                    lineObj.YData = yyy;
                end

                app.coeffx = app.temp_coeffx;
                updateFields(app);
            end
        end

        % Value changed function: invertgraphCheckBox
        function invertgraphCheckBoxValueChanged(app, event)
            value = app.invertgraphCheckBox.Value;
            app.invert_graph = value;

            temp_updateFields(app);
        end

        % Value changed function: coef_X5EditField
        function coef_X5EditFieldValueChanged(app, event)
            value = app.coef_X5EditField.Value;
            app.coeffx(1) = value;
            updateFields(app);
            app.temp_coeffx = app.coeffx;
            temp_updateFields(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {648, 648};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SettouseButton = uibutton(app.LeftPanel, 'push');
            app.SettouseButton.ButtonPushedFcn = createCallbackFcn(app, @SettouseButtonPushed, true);
            app.SettouseButton.Text = 'Set to use';
            app.invertgraphCheckBox = uicheckbox(app.LeftPanel);
            app.invertgraphCheckBox.ValueChangedFcn = createCallbackFcn(app, @invertgraphCheckBoxValueChanged, true);
            app.invertgraphCheckBox.Text = 'invert graph';
            app.AdjustcurvesbyclickingLabel = uilabel(app.LeftPanel);
            app.AdjustcurvesbyclickingLabel.Text = 'Adjust curves by clicking';
            app.andholdingonthelineLabel = uilabel(app.LeftPanel);
            app.andholdingonthelineLabel.Text = 'and holding on the line, ';
            app.draggingittothedesiredLabel = uilabel(app.LeftPanel);
            app.draggingittothedesiredLabel.Text = 'dragging it to the desired';
            app.loationthenreleasingtheLabel = uilabel(app.LeftPanel);
            app.loationthenreleasingtheLabel.Text = 'loation, then releasing the ';
            app.mousebuttonLabel = uilabel(app.LeftPanel);
            app.mousebuttonLabel.Text = 'mouse button.';
            app.RecalculatecoefficientsLabel = uilabel(app.LeftPanel);
            app.RecalculatecoefficientsLabel.Text = 'Re-calculate coefficients';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.maxxcmEditFieldLabel = uilabel(app.RightPanel);
            app.maxxcmEditFieldLabel.Text = 'max x (cm)';
            app.maxxcmEditField = uieditfield(app.RightPanel, 'numeric');
            app.maxxcmEditField.ValueChangedFcn = createCallbackFcn(app, @maxxcmEditFieldValueChanged, true);
            app.coef_X0EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X0EditFieldLabel.Text = 'coef_X0';
            app.coef_X0EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X0EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditFieldValueChanged, true);
            app.coef_X1EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X1EditFieldLabel.Text = 'coef_X1';
            app.coef_X1EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X1EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditFieldValueChanged, true);
            app.coef_X2EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X2EditFieldLabel.Text = 'coef_X2';
            app.coef_X2EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X2EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditFieldValueChanged, true);
            app.coef_X3EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X3EditFieldLabel.Text = 'coef_X3';
            app.coef_X3EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X3EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditFieldValueChanged, true);
            app.MeasurementsPanel = uipanel(app.RightPanel);
            app.MeasurementsPanel.Title = 'Measurements';
            app.MaxYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MaxYEditFieldLabel.Text = 'Max Y ';
            app.MaxYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.MinYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.MinYEditFieldLabel.Text = 'Min Y';
            app.MinYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.TraceXcmEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.TraceXcmEditFieldLabel.Text = 'Trace X (cm)';
            app.TraceXcmEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.TraceXcmEditField.ValueChangedFcn = createCallbackFcn(app, @TraceXcmEditFieldValueChanged, true);
            app.TraceYEditFieldLabel = uilabel(app.MeasurementsPanel);
            app.TraceYEditFieldLabel.Text = 'Trace Y';
            app.TraceYEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.LButton = uibutton(app.MeasurementsPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.MeasurementsPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.MaxY_XLabel = uilabel(app.MeasurementsPanel);
            app.MaxY_XLabel.Text = 'MaxY_X (cm)';
            app.MaxY_XcmEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.MinY_XLabel = uilabel(app.MeasurementsPanel);
            app.MinY_XLabel.Text = 'MinY_X (cm)';
            app.MinY_XcmEditField = uieditfield(app.MeasurementsPanel, 'numeric');
            app.coef_X4EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X4EditFieldLabel.Text = 'coef_X4';
            app.coef_X4EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X4EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditFieldValueChanged, true);
            app.ExportButton = uibutton(app.RightPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.mLabel = uilabel(app.RightPanel);
            app.mLabel.Text = '(m)';
            app.coef_X5EditFieldLabel = uilabel(app.RightPanel);
            app.coef_X5EditFieldLabel.Text = 'coef_X5';
            app.coef_X5EditField = uieditfield(app.RightPanel, 'numeric');
            app.coef_X5EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X5EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramAdjustApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end