classdef thieleSmallCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_7                         matlab.ui.container.Panel
        NonlinearityforKPanel           matlab.ui.container.Panel
        ReloadButton_3                  matlab.ui.control.Button
        coef_X4EditField_3              matlab.ui.control.NumericEditField
        coef_X4EditField_3Label         matlab.ui.control.Label
        coef_X3EditField_3              matlab.ui.control.NumericEditField
        coef_X3EditField_3Label         matlab.ui.control.Label
        coef_X0EditField_3              matlab.ui.control.NumericEditField
        coef_X0EditField_3Label         matlab.ui.control.Label
        coef_X1EditField_3              matlab.ui.control.NumericEditField
        coef_X1EditField_3Label         matlab.ui.control.Label
        coef_X2EditField_3              matlab.ui.control.NumericEditField
        coef_X2EditField_3Label         matlab.ui.control.Label
        AdjustgraphButton_3             matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        Panel_6                         matlab.ui.container.Panel
        NonlinearityforLPanel           matlab.ui.container.Panel
        ReloadButton_2                  matlab.ui.control.Button
        coef_X4EditField_2              matlab.ui.control.NumericEditField
        coef_X4EditField_2Label         matlab.ui.control.Label
        coef_X3EditField_2              matlab.ui.control.NumericEditField
        coef_X3EditField_2Label         matlab.ui.control.Label
        coef_X0EditField_2              matlab.ui.control.NumericEditField
        coef_X0EditField_2Label         matlab.ui.control.Label
        coef_X1EditField_2              matlab.ui.control.NumericEditField
        coef_X1EditField_2Label         matlab.ui.control.Label
        coef_X2EditField_2              matlab.ui.control.NumericEditField
        coef_X2EditField_2Label         matlab.ui.control.Label
        AdjustgraphButton_2             matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        Panel_5                         matlab.ui.container.Panel
        NonlinearityforBPanel           matlab.ui.container.Panel
        ReloadButton                    matlab.ui.control.Button
        coef_X4EditField                matlab.ui.control.NumericEditField
        coef_X4EditFieldLabel           matlab.ui.control.Label
        coef_X3EditField                matlab.ui.control.NumericEditField
        coef_X3EditFieldLabel           matlab.ui.control.Label
        coef_X0EditField                matlab.ui.control.NumericEditField
        coef_X0EditFieldLabel           matlab.ui.control.Label
        coef_X1EditField                matlab.ui.control.NumericEditField
        coef_X1EditFieldLabel           matlab.ui.control.Label
        coef_X2EditField                matlab.ui.control.NumericEditField
        coef_X2EditFieldLabel           matlab.ui.control.Label
        AdjustgraphButton               matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        Panel_3                         matlab.ui.container.Panel
        RecommendationPanel             matlab.ui.container.Panel
        volmatRmdEditField              matlab.ui.control.NumericEditField
        volmatRmdEditFieldLabel         matlab.ui.control.Label
        VolboxRmdEditField              matlab.ui.control.NumericEditField
        VolboxRmdEditFieldLabel         matlab.ui.control.Label
        volumeairRmdEditField           matlab.ui.control.NumericEditField
        volumeairRmdEditFieldLabel      matlab.ui.control.Label
        GeneralpropertiesPanel          matlab.ui.container.Panel
        rhokgm3EditField                matlab.ui.control.NumericEditField
        rhokgm3EditFieldLabel           matlab.ui.control.Label
        PaEditField                     matlab.ui.control.NumericEditField
        PaLabel                         matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        gammaLabel                      matlab.ui.control.Label
        cmsEditField                    matlab.ui.control.NumericEditField
        cmsEditFieldLabel               matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        AcousticmaterialPanel           matlab.ui.container.Panel
        materialticknessmmEditField     matlab.ui.control.NumericEditField
        materialticknessmmEditFieldLabel  matlab.ui.control.Label
        Volumeairm3EditField            matlab.ui.control.NumericEditField
        Volumeairm3EditFieldLabel       matlab.ui.control.Label
        VolratioVaVmEditField           matlab.ui.control.NumericEditField
        VolratioVaVmEditFieldLabel      matlab.ui.control.Label
        materialRfraylmmEditField       matlab.ui.control.NumericEditField
        materialRfraylmmEditFieldLabel  matlab.ui.control.Label
        Volumematerialm3EditField       matlab.ui.control.NumericEditField
        Volumematerialm3EditFieldLabel  matlab.ui.control.Label
        powerPanel                      matlab.ui.container.Panel
        InputpowerWEditField            matlab.ui.control.NumericEditField
        InputpowerWEditFieldLabel       matlab.ui.control.Label
        estxmaxcmEditField              matlab.ui.control.NumericEditField
        estxmaxcmEditFieldLabel         matlab.ui.control.Label
        EnclosureInterpretedparamPanel  matlab.ui.container.Panel
        FirstmodefreqEditField          matlab.ui.control.NumericEditField
        FirstmodefreqEditFieldLabel     matlab.ui.control.Label
        EffVolumem3EditField            matlab.ui.control.NumericEditField
        EffVolumem3EditFieldLabel       matlab.ui.control.Label
        QtcEditField_2                  matlab.ui.control.NumericEditField
        QtcEditField_2Label             matlab.ui.control.Label
        FcHzEditField                   matlab.ui.control.NumericEditField
        FcHzEditFieldLabel              matlab.ui.control.Label
        SpeakerEnclosurePanel           matlab.ui.container.Panel
        boxdepthmEditField              matlab.ui.control.NumericEditField
        boxdepthmEditFieldLabel         matlab.ui.control.Label
        backaream2EditField             matlab.ui.control.NumericEditField
        backaream2Label                 matlab.ui.control.Label
        Volumeboxm3EditField            matlab.ui.control.NumericEditField
        Volumeboxm3Label                matlab.ui.control.Label
        SpeakerPortsPanel               matlab.ui.container.Panel
        portlengthmEditField            matlab.ui.control.NumericEditField
        portlengthmEditFieldLabel       matlab.ui.control.Label
        portradiusmEditField            matlab.ui.control.NumericEditField
        portradiusmEditFieldLabel       matlab.ui.control.Label
        numportsEditField               matlab.ui.control.NumericEditField
        numportsEditFieldLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        TSinterpretedparamPanel         matlab.ui.container.Panel
        QtsEditField                    matlab.ui.control.NumericEditField
        QtsEditFieldLabel               matlab.ui.control.Label
        Decayrate92Label                matlab.ui.control.Label
        pifs2Qtss1EditField             matlab.ui.control.NumericEditField
        pifs2Qtss1EditFieldLabel        matlab.ui.control.Label
        ThieleSmallParametersPanel_2    matlab.ui.container.Panel
        VasLEditField                   matlab.ui.control.NumericEditField
        VasLEditFieldLabel              matlab.ui.control.Label
        Sdm2EditField                   matlab.ui.control.NumericEditField
        Sdm2EditFieldLabel              matlab.ui.control.Label
        fsHzEditField                   matlab.ui.control.NumericEditField
        fsHzEditFieldLabel              matlab.ui.control.Label
        QmsEditField                    matlab.ui.control.NumericEditField
        QmsEditFieldLabel               matlab.ui.control.Label
        QesEditField                    matlab.ui.control.NumericEditField
        QesEditFieldLabel               matlab.ui.control.Label
        ReEditField                     matlab.ui.control.NumericEditField
        ReEditFieldLabel                matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        MechanicalParametersPanel       matlab.ui.container.Panel
        inrmEditField                   matlab.ui.control.NumericEditField
        inrmEditFieldLabel              matlab.ui.control.Label
        outrmEditField                  matlab.ui.control.NumericEditField
        outrmEditFieldLabel             matlab.ui.control.Label
        xmaxcmEditField                 matlab.ui.control.NumericEditField
        xmaxcmEditFieldLabel            matlab.ui.control.Label
        rmkgsEditField                  matlab.ui.control.NumericEditField
        rmkgsEditFieldLabel             matlab.ui.control.Label
        cmmNEditField                   matlab.ui.control.NumericEditField
        cmmNEditFieldLabel              matlab.ui.control.Label
        mmkgEditField                   matlab.ui.control.NumericEditField
        mmkgEditFieldLabel              matlab.ui.control.Label
        ReloadButton_4                  matlab.ui.control.Button
        ExportButton_2                  matlab.ui.control.Button
        ElectricParametersPanel         matlab.ui.container.Panel
        BlNAEditField                   matlab.ui.control.NumericEditField
        BlNAEditFieldLabel              matlab.ui.control.Label
        LeHEditField                    matlab.ui.control.NumericEditField
        LeHEditFieldLabel               matlab.ui.control.Label
        ReohmsEditField                 matlab.ui.control.NumericEditField
        ReohmsEditFieldLabel            matlab.ui.control.Label
    end

      
    properties (Access = private)
        state % param structure to be passed into and out of this app
        
        decayRate % Description
        
        Blxx % Description
        Ldxx % Description
        Kdxx % Description
        Blxx_orig % Description
        Ldxx_orig % Description
        Kdxx_orig % Description
        
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        function results = calcVas(app)
            results = 1;
            app.state.Vas = app.state.mech.cm * app.state.Sd*app.state.Sd* app.state.rho*app.state.c*app.state.c;
            app.VasLEditField.Value = app.state.Vas * 1000;  % change unit to Litre
        end

        function results = updateThiele(app)
            results = 1;

            app.state = updateTSparam(app.state);
            
            app.ReohmsEditField.Value = app.state.elec.Re;
            app.ReEditField.Value = app.state.elec.Re;
            
            app.state.Qms = sqrt(app.state.mech.mm / app.state.mech.cm)/app.state.mech.rm; % sqrt( mm k )/D
            app.QmsEditField.Value = app.state.Qms;
            
            app.state.Qes = app.state.elec.Re/(app.state.elec.Bl*app.state.elec.Bl)*sqrt(app.state.mech.mm/app.state.mech.cm);            
            app.QesEditField.Value = app.state.Qes;
            
            app.state.fs = 1/(2*pi*sqrt(app.state.mech.mm*app.state.mech.cm));
            app.fsHzEditField.Value = app.state.fs;
            
            
            if app.state.mech.ir > 0.001
               app.state.Sd = pi/3*(app.state.mech.or*app.state.mech.or+app.state.mech.or*app.state.mech.ir+app.state.mech.ir*app.state.mech.ir);
            else
               app.state.Sd = pi*app.state.mech.or*app.state.mech.or;
            end
            app.Sdm2EditField.Value = app.state.Sd;
            app.state.Vas = app.state.mech.cm * app.state.Sd*app.state.Sd* app.state.rho*app.state.c*app.state.c;
            app.VasLEditField.Value = app.state.Vas * 1000;  % change unit to Litre
            
            app.state.Qts = 1/(1/app.state.Qes+1/app.state.Qms);
            app.QtsEditField.Value = app.state.Qts;
            
            % update param in Other 
            app.decayRate = 2*pi*app.state.fs / (2 * app.state.Qts);
            app.pifs2Qtss1EditField.Value = app.decayRate;
            
        end
    end


    methods (Access = private)
        
        function doExportParams(app)
            % export box params
%             rho = 1.2041;   % density of air, 1.2041 kg/m^3  at 20 Celcius, 1.1839 at 25 
%             c = 343.21;     % speed of sound 343.21 m/s at 20 Celcius, 346.13 at 25
%             
%             rear_M = rho * app.beta * app.rd / app.Sd;
%             rear_C = app.boxVol / (rho * c * c);
                        
            assignin("base",'state',app.state);

            lBlxx = app.Blxx_orig;
            lBlxx(1) = app.state.elec.Bl;
            assignin("base",'Blxx',(lBlxx));            
            processNewBlxx(app, lBlxx);

            lLdxx = app.Ldxx_orig;
            lLdxx(1) = app.state.elec.Le;
            assignin("base",'Ldxx',(lLdxx));            
            processNewLdxx(app, lLdxx);
            
            lKdxx = app.Kdxx_orig;
            lKdxx(1) = 1/app.state.mech.cm;
            assignin("base",'Kdxx',(lKdxx));            
            processNewKdxx(app, lKdxx);
            
            
%             
            assignin("base",'pressure_factor', app.state.Sd);
            
        end
        
        function processNewBlxx(app, newBlxx)
            app.Blxx_orig = newBlxx;
            app.Blxx = zeros(5,1);
            app.coef_X0EditField.Value = newBlxx(1);
            app.Blxx(5) = newBlxx(1);
            app.coef_X1EditField.Value = newBlxx(2);
            app.Blxx(4) = newBlxx(2);
            app.coef_X2EditField.Value = newBlxx(3);
            app.Blxx(3) = newBlxx(3);
            app.coef_X3EditField.Value = newBlxx(4);
            app.Blxx(2) = newBlxx(4);
            app.coef_X4EditField.Value = newBlxx(5);
            app.Blxx(1) = newBlxx(5);

            
            % plot graph Blxx           
            % put values into ymax, ymin, xx            
            mycoeff = app.Blxx;
            strTitle = 'Bl force vs distance';
            strylabel = 'Newton / A';
            xx = app.state.mech.xmax;
            xxx = -xx: xx/10: xx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            line(app.UIAxes_4, xcm, yyy);
            title(app.UIAxes_4, strTitle)
            grid(app.UIAxes_4, 'on')
            xlabel(app.UIAxes_4, 'position (cm)');
            ylabel(app.UIAxes_4, strylabel );            
        end
        
        function processNewLdxx(app, newLdxx)
            app.Ldxx_orig = newLdxx;
            app.Ldxx = zeros(5,1);
            app.coef_X0EditField_2.Value = newLdxx(1);
            app.Ldxx(5) = newLdxx(1);
            app.coef_X1EditField_2.Value = newLdxx(2);
            app.Ldxx(4) = newLdxx(2);
            app.coef_X2EditField_2.Value = newLdxx(3);
            app.Ldxx(3) = newLdxx(3);
            app.coef_X3EditField_2.Value = newLdxx(4);
            app.Ldxx(2) = newLdxx(4);
            app.coef_X4EditField_2.Value = newLdxx(5);
            app.Ldxx(1) = newLdxx(5);
            
            % plot graph Ldxx           
            % put values into ymax, ymin, xx            
            mycoeff = app.Ldxx;
            strTitle = 'Inductance vs distance';
            strylabel = 'mH';
            xx = app.state.mech.xmax;
            xxx = -xx: xx/10: xx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            line(app.UIAxes_2, xcm, yyy);
            title(app.UIAxes_2, strTitle)
            grid(app.UIAxes_2, 'on')
            xlabel(app.UIAxes_2, 'position (cm)');
            ylabel(app.UIAxes_2, strylabel );               
        end
        
        function processNewKdxx(app, newKdxx)
            app.Kdxx_orig = newKdxx;
            app.Kdxx = zeros(5,1);
            app.coef_X0EditField_3.Value = newKdxx(1);
            app.Kdxx(5) = newKdxx(1);
            app.coef_X1EditField_3.Value = newKdxx(2);
            app.Kdxx(4) = newKdxx(2);
            app.coef_X2EditField_3.Value = newKdxx(3);
            app.Kdxx(3) = newKdxx(3);
            app.coef_X3EditField_3.Value = newKdxx(4);
            app.Kdxx(2) = newKdxx(4);
            app.coef_X4EditField_3.Value = newKdxx(5);
            app.Kdxx(1) = newKdxx(5);
            
            
            % plot graph Kdxx           
            % put values into ymax, ymin, xx
            mycoeff = app.Kdxx;
            strTitle = 'compliance vs distance';
            strylabel = 'compliance (m/N)';
            xx = app.state.mech.xmax;
            xxx = -xx: xx/10: xx;
            xcm = xxx*100;
            yyy = polyval(mycoeff, xxx);
            line(app.UIAxes_3, xcm, 1./yyy);
            title(app.UIAxes_3, strTitle)
            grid(app.UIAxes_3, 'on')
            xlabel(app.UIAxes_3, 'position (cm)');
            ylabel(app.UIAxes_3, strylabel );            
        end
        
        function setSysParam(app, lstate)
            app.state = lstate;
                        
            %% elec param
            app.ReohmsEditField.Value = app.state.elec.Re;
            app.ReEditField.Value = app.state.elec.Re;            
            app.LeHEditField.Value = app.state.elec.Le;
            app.BlNAEditField.Value = app.state.elec.Bl;
            
            %% mech param
            app.mmkgEditField.Value = app.state.mech.mm;
            app.cmmNEditField.Value = app.state.mech.cm;
            app.rmkgsEditField.Value = app.state.mech.rm;
            app.xmaxcmEditField.Value = app.state.mech.xmax * 100;            
            app.outrmEditField.Value = app.state.mech.or;
            app.inrmEditField.Value = app.state.mech.ir; 
            
            
            %% box param
            app.backaream2EditField.Value = app.state.box.Sm;
            app.boxdepthmEditField.Value = app.state.box.vol / app.state.box.Sm;
            app.Volumeboxm3EditField.Value = app.state.box.vol;

            %% box -- ports
            app.numportsEditField.Value = app.state.box.numPorts;
            app.portradiusmEditField.Value = app.state.box.portRadius;
            app.portlengthmEditField.Value = app.state.box.portLen;

            %% box -- acoustic materials
            app.materialticknessmmEditField.Value = app.state.box.mthick;
            app.Volumeairm3EditField.Value = app.state.box.Va;
            app.VolratioVaVmEditField.Value = app.state.box.vratio;
            app.materialRfraylmmEditField.Value = app.state.box.Rf;
            app.Volumematerialm3EditField.Value = app.state.box.Vm;
                        
            %% box -- interpreted param
            app.EffVolumem3EditField.Value = app.state.box.Vab;
            app.FirstmodefreqEditField.Value = app.state.box.firstModeFreq;
            app.QtcEditField_2.Value = app.state.Qtc;
            app.FcHzEditField.Value = app.state.fc;
            
            %% box -- recommended
            app.volmatRmdEditField.Value = app.state.box.Vm_Rmd;
            app.VolboxRmdEditField.Value = app.state.box.vol_Rmd;
            app.volumeairRmdEditField.Value = app.state.box.Va_Rmd;
            
            %% power
            app.InputpowerWEditField.Value = app.state.inputPower;
            app.estxmaxcmEditField.Value = app.state.est_xmax * 100;
            
            %% general properties
            app.rhokgm3EditField.Value = app.state.rho;
            app.cmsEditField.Value = app.state.c;
            app.gammaEditField.Value = app.state.gamma;
            app.PaEditField.Value = app.state.P0;
            
            updateThiele(app);
        end
        
        function results = showBoxParam(app)
            results = 1;
            app.FcHzEditField.Value = app.state.fc;
            app.VolboxRmdEditField.Value = app.state.box.vol_Rmd;
            app.volumeairRmdEditField.Value = app.state.box.Va_Rmd;            
            app.volmatRmdEditField.Value = app.state.box.Vm_Rmd;
            app.QtcEditField_2.Value = app.state.Qtc;
            app.EffVolumem3EditField.Value = app.state.box.Vab;
            app.FirstmodefreqEditField.Value = app.state.box.firstModeFreq;
        end
        
        function results = showBoxSize(app)
            results = 1;
            app.backaream2EditField.Value = app.state.box.Sm;
            app.boxdepthmEditField.Value = app.state.box.vol/app.state.box.Sm;
            app.Volumeboxm3EditField.Value = app.state.box.vol;
            app.materialRfraylmmEditField.Value = app.state.box.Rf;
            app.materialticknessmmEditField.Value = app.state.box.mthick;
            app.Volumematerialm3EditField.Value = app.state.box.Vm;
            app.VolratioVaVmEditField.Value = app.state.box.vratio;
            app.Volumeairm3EditField.Value = app.state.box.Va;
            
        end
        
        function results = setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd)
            results = 1;
            app.state.rear_M = Mab;
            app.state.rear_C = Cab;
            app.state.rear_R = Rab;
            app.state.box.vol_Rmd = vol_Rmd;
            app.state.box.Va_Rmd = Va_Rmd;
            app.state.box.Vm_Rmd = Vm_Rmd;
            app.state.box.boxDepth_Rmd= boxDepth_Rmd; 
            
            app.VolboxRmdEditField.Value = vol_Rmd;
            app.volumeairRmdEditField.Value = Va_Rmd;
            app.volmatRmdEditField.Value = Vm_Rmd;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            init_speaker_st_nl_cload;  % loading the default parameters

            assignin('base','state',state)
            assignin('base','Blxx',Blxx)
            assignin('base','Kdxx',Kdxx)
            assignin('base','Ldxx',Ldxx)
            
            setSysParam(app, state);
            
            % put values into Blxx
            newBlxx = evalin('base', 'Blxx');
            processNewBlxx(app, newBlxx);
                        
            % put values into Ldxx
            newLdxx = evalin('base', 'Ldxx');
            processNewLdxx(app, newLdxx);
                        
            % put values into Kdxx
            newKdxx = evalin('base', 'Kdxx');
            processNewKdxx(app, newKdxx);
        end

        % Value changed function: ReohmsEditField
        function ReohmsEditFieldValueChanged(app, event)
            value = app.ReohmsEditField.Value;
            app.state.elec.Re = value;
            updateThiele(app);      
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: LeHEditField
        function LeHEditFieldValueChanged(app, event)

            value = app.LeHEditField.Value;
            app.state.elec.Le = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Ldxx_orig(1) = app.state.elec.Le;
            processNewLdxx(app, app.Ldxx_orig);
        end

        % Value changed function: BlNAEditField
        function BlNAEditFieldValueChanged(app, event)
            value = app.BlNAEditField.Value;
            app.state.elec.Bl = value;
            updateThiele(app);        
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Blxx_orig(1) = app.state.elec.Bl;
            processNewBlxx(app, app.Blxx_orig);
        end

        % Value changed function: mmkgEditField
        function mmkgEditFieldValueChanged(app, event)
            value = app.mmkgEditField.Value;
            app.state.mech.mm = value;
            updateThiele(app);
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: cmmNEditField
        function cmmNEditFieldValueChanged(app, event)
            value = app.cmmNEditField.Value;
            app.state.mech.cm = value;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Kdxx_orig(1) = 1/app.state.mech.cm;
            processNewKdxx(app, app.Kdxx_orig);            
        end

        % Value changed function: rmkgsEditField
        function rmkgsEditFieldValueChanged(app, event)
            value = app.rmkgsEditField.Value;
            app.state.mech.rm = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: xmaxcmEditField
        function xmaxcmEditFieldValueChanged(app, event)
            value = app.xmaxcmEditField.Value;
            app.state.mech.xmax = value /100;            
        end

        % Value changed function: outrmEditField
        function outrmEditFieldValueChanged(app, event)
            value = app.outrmEditField.Value;
            app.state.mech.or = value;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: inrmEditField
        function inrmEditFieldValueChanged(app, event)
            value = app.inrmEditField.Value;
            app.state.mech.ir = value;
            updateThiele(app);    
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
        end

        % Callback function
        function EffVolumem3EditFieldValueChanged(app, event)
            %value = app.EffVolumem3EditField.Value;
            % value cannot be modified
            app.EffVolumem3EditField.Value = app.state.box.Vab;
        end

        % Value changed function: numportsEditField
        function numportsEditFieldValueChanged(app, event)
            %value = app.numportsEditField.Value;
            % value cannot be modified
            app.numportsEditField.Value = 0;            
        end

        % Value changed function: portradiusmEditField
        function portradiusmEditFieldValueChanged(app, event)
            %value = app.portradiusmEditField.Value;
            % value cannot be modified
            app.portradiusmEditField.Value = 0;            
        end

        % Value changed function: portlengthmEditField
        function portlengthmEditFieldValueChanged(app, event)
            %value = app.portlengthmEditField.Value;
            % value cannot be modified
            app.portlengthmEditField.Value = 0;
        end

        % Button pushed function: ExportButton_2
        function ExportButton_2Pushed(app, event)
            % this is for elec param
%            app.state.elec.Re = app.Re;
%            app.state.elec.Le = app.Le;
%            app.state.elec.Bl = app.Bl;
            
            % this is for mech param
%            app.state.mech.mm = app.mm;
%            app.state.mech.cm = app.cm;
%            app.state.mech.rm = app.rm;
%            app.state.mech.xmax = app.xmax;
%            app.state.mech.or = app.out_r;
%            app.state.mech.ir = app.inner_r;
            
            % this is for box param
            %rho = 1.2041;   % density of air, 1.2041 kg/m^3  at 20 Celcius, 1.1839 at 25 
            %c = 343.21;     % speed of sound 343.21 m/s at 20 Celcius, 346.13 at 25
            
%             app.state.box.rear_M = app.state.rho * app.beta * app.rd / app.Sd;
%             app.state.box.rear_C = app.boxVol / (app.state.rho * app.cc * app.cc);
%             app.state.box.front_M = 1;
%             app.state.box.front_R = 1;
            
            doExportParams(app);
            
            
        end

        % Value changed function: ReEditField
        function ReEditFieldValueChanged(app, event)
            value = app.ReEditField.Value;
            app.state.elec.Re = value;
            app.ReohmsEditField.Value = value;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: QesEditField
        function QesEditFieldValueChanged(app, event)
            value = app.QesEditField.Value;
            app.state.Qes = value;
            app.state.elec.Bl = sqrt(app.state.elec.Re * sqrt(app.state.mech.mm / app.state.mech.cm) / app.state.Qes);
            app.BlNAEditField.Value = app.state.elec.Bl;
            updateThiele(app);     
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
            
            % update nonlinear graph
            app.Blxx_orig(1) = app.state.elec.Bl;
            processNewBlxx(app, app.Blxx_orig);
        end

        % Value changed function: QmsEditField
        function QmsEditFieldValueChanged(app, event)
            value = app.QmsEditField.Value;
            app.state.Qms = value;
            app.state.mech.rm = sqrt(app.state.mech.mm / app.state.mech.cm) / app.state.Qms;
            app.rmkgsEditField.Value = app.state.mech.rm;
            updateThiele(app); 
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: fsHzEditField
        function fsHzEditFieldValueChanged(app, event)
            value = app.fsHzEditField.Value;
            app.state.fs = value;
            app.state.mech.cm = (1/(2*pi*app.state.fs))*(1/(2*pi*app.state.fs))/ app.state.mech.mm;  
            app.cmmNEditField.Value = app.state.mech.cm;
            updateThiele(app);            
            app.state.fc = calFc_fsQtcQts(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
            
            % update nonlinear graph
            app.Kdxx_orig(1) = 1/app.state.mech.cm;
            processNewKdxx(app, app.Kdxx_orig);            
            
        end

        % Value changed function: Sdm2EditField
        function Sdm2EditFieldValueChanged(app, event)
            value = app.Sdm2EditField.Value;
            app.state.Sd = value;
  %          app.area_scale = (app.Sd/pi)/(app.state.mech.or*app.state.mech.or);
            calcVas(app); 
                        
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);            
        end

        % Value changed function: VasLEditField
        function VasLEditFieldValueChanged(app, event)
            %value = app.VasLEditField.Value;
            % change is not allowed
            app.VasLEditField.Value = app.state.Vas;
        end

        % Value changed function: QtsEditField
        function QtsEditFieldValueChanged(app, event)
            % value = app.QtsEditField.Value;
            % change is not allowed            
            app.QtsEditField.Value = app.state.Qts;            
        end

        % Value changed function: pifs2Qtss1EditField
        function pifs2Qtss1EditFieldValueChanged(app, event)
            % value = app.pifs2Qtss1EditField.Value;
            % change is not allowed            
            app.pifs2Qtss1EditField.Value = app.decayRate;            
        end

        % Value changed function: coef_X0EditField
        function coef_X0EditFieldValueChanged(app, event)
            % value = app.coef_X0EditField.Value;
            % change is not allowed 
             app.coef_X0EditField.Value = app.Blxx(5);
        end

        % Value changed function: coef_X1EditField
        function coef_X1EditFieldValueChanged(app, event)
            % value = app.coef_X1EditField.Value;
            % change is not allowed 
             app.coef_X1EditField.Value = app.Blxx(4);
        end

        % Value changed function: coef_X2EditField
        function coef_X2EditFieldValueChanged(app, event)
            %value = app.coef_X2EditField.Value;
            % change is not allowed 
             app.coef_X2EditField.Value = app.Blxx(3);            
        end

        % Value changed function: coef_X3EditField
        function coef_X3EditFieldValueChanged(app, event)
            %value = app.coef_X3EditField.Value;
            % change is not allowed 
             app.coef_X3EditField.Value = app.Blxx(2);            
        end

        % Value changed function: coef_X4EditField
        function coef_X4EditFieldValueChanged(app, event)
            %value = app.coef_X4EditField.Value;
            % change is not allowed 
             app.coef_X4EditField.Value = app.Blxx(1);            
        end

        % Value changed function: coef_X0EditField_2
        function coef_X0EditField_2ValueChanged(app, event)
            %value = app.coef_X0EditField_2.Value;
            % change is not allowed 
             app.coef_X0EditField_2.Value = app.Ldxx(5);            
        end

        % Value changed function: coef_X1EditField_2
        function coef_X1EditField_2ValueChanged(app, event)
            %value = app.coef_X1EditField_2.Value;
            % change is not allowed 
             app.coef_X1EditField_2.Value = app.Ldxx(4);            
        end

        % Value changed function: coef_X2EditField_2
        function coef_X2EditField_2ValueChanged(app, event)
            %value = app.coef_X2EditField_2.Value;
            % change is not allowed 
             app.coef_X2EditField_2.Value = app.Ldxx(3);            
        end

        % Value changed function: coef_X3EditField_2
        function coef_X3EditField_2ValueChanged(app, event)
            %value = app.coef_X3EditField_2.Value;
            % change is not allowed 
             app.coef_X3EditField_2.Value = app.Ldxx(2);
        end

        % Value changed function: coef_X4EditField_2
        function coef_X4EditField_2ValueChanged(app, event)
            %value = app.coef_X4EditField_2.Value;
            % change is not allowed 
             app.coef_X4EditField_2.Value = app.Ldxx(1);            
        end

        % Value changed function: coef_X0EditField_3
        function coef_X0EditField_3ValueChanged(app, event)
            %value = app.coef_X0EditField_3.Value;
            % change is not allowed 
             app.coef_X0EditField_3.Value = app.Kdxx(5);            
        end

        % Value changed function: coef_X1EditField_3
        function coef_X1EditField_3ValueChanged(app, event)
            %value = app.coef_X1EditField_3.Value;
            % change is not allowed 
             app.coef_X1EditField_3.Value = app.Kdxx(4);            
        end

        % Value changed function: coef_X2EditField_3
        function coef_X2EditField_3ValueChanged(app, event)
            %value = app.coef_X2EditField_3.Value;
            % change is not allowed 
             app.coef_X2EditField_3.Value = app.Kdxx(3);             
        end

        % Value changed function: coef_X3EditField_3
        function coef_X3EditField_3ValueChanged(app, event)
            %value = app.coef_X3EditField_3.Value;
            % change is not allowed 
             app.coef_X3EditField_3.Value = app.Kdxx(2);             
        end

        % Value changed function: coef_X4EditField_3
        function coef_X4EditField_3ValueChanged(app, event)
            %value = app.coef_X4EditField_3.Value;
            % change is not allowed 
             app.coef_X4EditField_3.Value = app.Kdxx(1);             
        end

        % Button pushed function: AdjustgraphButton
        function AdjustgraphButtonPushed(app, event)
            strTitle = 'Bl force vs distance';
            strylabel = 'Newton / A';            
            paramtype = 1;  % 1 = Bl, 2 = H, 3 = L
            lBlxx = app.Blxx_orig;
            lxmax = app.state.mech.xmax;
            paramAdjustApp(lxmax, lBlxx, paramtype , strTitle, strylabel);
        end

        % Button pushed function: AdjustgraphButton_2
        function AdjustgraphButton_2Pushed(app, event)
            strTitle = 'Inductance vs distance';
            strylabel = 'H';            
            paramtype = 3;  % 1 = Bl, 2 = H, 3 = L
            paramAdjustApp(app.state.mech.xmax, app.Ldxx_orig, paramtype , strTitle, strylabel);            
        end

        % Button pushed function: AdjustgraphButton_3
        function AdjustgraphButton_3Pushed(app, event)
            strTitle = '1/compliance vs distance';
            strylabel = '1/compliance (N/m)';            
            paramtype = 2;  % 1 = Bl, 2 = H, 3 = L
            paramAdjustApp(app.state.mech.xmax, app.Kdxx_orig, paramtype , strTitle, strylabel);                   
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            newBlxx = evalin('base', 'Blxx');
            processNewBlxx(app, newBlxx);
            
        end

        % Button pushed function: ReloadButton_2
        function ReloadButton_2Pushed(app, event)
            newLdxx = evalin('base', 'Ldxx');
            processNewLdxx(app, newLdxx);
        end

        % Button pushed function: ReloadButton_3
        function ReloadButton_3Pushed(app, event)
            newKdxx = evalin('base', 'Kdxx');
            processNewKdxx(app, newKdxx);            
        end

        % Button pushed function: ReloadButton_4
        function ReloadButton_4Pushed(app, event)
            lstate = evalin('base', 'state');
            setSysParam(app, lstate);                  
        end

        % Value changed function: rhokgm3EditField
        function rhokgm3EditFieldValueChanged(app, event)
            value = app.rhokgm3EditField.Value;
            app.state.rho = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: cmsEditField
        function cmsEditFieldValueChanged(app, event)
            value = app.cmsEditField.Value;
            app.state.c = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: gammaEditField
        function gammaEditFieldValueChanged(app, event)
            value = app.gammaEditField.Value;
            app.state.gamma = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: PaEditField
        function PaEditFieldValueChanged(app, event)
            value = app.PaEditField.Value;
            app.state.box.P0 = value;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            web('paramsPanelHelp.html')
        end

        % Value changed function: backaream2EditField
        function backaream2EditFieldValueChanged(app, event)
            value = app.backaream2EditField.Value;
            app.state.box.Vm = app.state.box.Vm * value/app.state.box.Sm;
            app.state.box.Sm = value;
            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, Qmb] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;            
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            app.EffVolumem3EditField.Value = Vab;
            showBoxSize(app);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc;
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            
        end

        % Value changed function: boxdepthmEditField
        function boxdepthmEditFieldValueChanged(app, event)
            value = app.boxdepthmEditField.Value;
            app.state.box.boxDepth = value;
            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);            
            Caa = calCaa_Va(app.state, app.state.box.Va);
            
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);             
%            app.state.boxDepth = app.state.box.vol / app.state.box.Sm;
            app.state.box.firstModeFreq = app.state.c / (2* app.state.box.boxDepth);
            %app.FirstmodefreqEditField.Value = app.state.firstModeFreq;
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            %app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: Volumeboxm3EditField
        function Volumeboxm3EditFieldValueChanged(app, event)
            value = app.Volumeboxm3EditField.Value;
            app.state.box.vol = value;
            app.state.box.boxDepth = value / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, ~, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;            
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);             
%            app.state.boxDepth = app.state.box.vol / app.state.box.Sm;
            app.state.firstModeFreq = app.state.c / (2* app.state.box.boxDepth);
%            app.FirstmodefreqEditField.Value = app.state.firstModeFreq;
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
%            app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: QtcEditField_2
        function QtcEditField_2ValueChanged(app, event)
            value = app.QtcEditField_2.Value;
            app.state.Qtc = value;
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            
        end

        % Value changed function: EffVolumem3EditField
        function EffVolumem3EditFieldValueChanged2(app, event)
            %value = app.EffVolumem3EditField.Value;
            % cannot be modified
            app.EffVolumem3EditField.Value = app.state.box.Vab;
        end

        % Value changed function: FcHzEditField
        function FcHzEditFieldValueChanged(app, event)
            value = app.FcHzEditField.Value;            
            app.state.fc = value;
            Qtc = calQtc_FcFsQts(app.state);
            app.state.Qtc = Qtc;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);            
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxParam(app);
        end

        % Value changed function: FirstmodefreqEditField
        function FirstmodefreqEditFieldValueChanged(app, event)
            value = app.FirstmodefreqEditField.Value;
            app.FirstmodefreqEditField.Value = value;
            app.state.box.boxDepth = app.state.c / (2* value);

            app.state.box.vol = app.state.box.Sm * app.state.box.boxDepth;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            Vab = calVab_vol(app.state);
            app.state.box.Vab = Vab;
            app.EffVolumem3EditField.Value = Vab;
            showBoxParam(app);
        end

        % Value changed function: materialRfraylmmEditField
        function materialRfraylmmEditFieldValueChanged(app, event)
            value = app.materialRfraylmmEditField.Value;
            app.state.box.Rf = value;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            %app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            %app.FcHzEditField.Value = fc;
            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: materialticknessmmEditField
        function materialticknessmmEditFieldValueChanged(app, event)
            value = app.materialticknessmmEditField.Value;
            
            if value <= 1
                  app.state.box.Rf = 412*3;
                  app.state.box.mthick = 1;
            end
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: VolratioVaVmEditField
        function VolratioVaVmEditFieldValueChanged(app, event)
            value = app.VolratioVaVmEditField.Value;
            if value < 0
                value = app.state.box.vratio;
            end
            
            app.state.box.vratio = value;
            app.state.box.Vm = app.state.box.vol / (1+value);  % eq. 7.22
            
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;            
            %app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: Volumematerialm3EditField
        function Volumematerialm3EditFieldValueChanged(app, event)
            value = app.Volumematerialm3EditField.Value;
            app.state.box.Vm = value;
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.Va = app.state.box.vol - app.state.box.Vm;            
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);            
        end

        % Value changed function: Volumeairm3EditField
        function Volumeairm3EditFieldValueChanged(app, event)
            value = app.Volumeairm3EditField.Value;
            app.state.box.Va = value;
            app.state.box.Vm = app.state.box.vol - app.state.box.Va;
            app.state.box.mthick = app.state.box.Vm / app.state.box.Sm;
            app.state.box.vratio = app.state.box.Va / app.state.box.Vm;
            
            Ram = calRam_RfDSm( app.state.box.Rf, app.state.box.mthick, app.state.box.Sm);
            %fc depends on Qtc
            Caa = calCaa_Va(app.state, app.state.box.Va);
            Rab = calRab_RamVaVmCaaFc(app.state, Ram, app.state.box.Va, app.state.box.Vm, Caa, app.state.fc);
            Qmb = calQmb_fromTSRab(app.state, Rab);
            app.state.Qmb = Qmb;
            Qtc = calQtc_fromTSVabQmb(app.state, app.state.box.Vab, Qmb);
            app.state.Qtc = Qtc;
            app.QtcEditField_2.Value = Qtc; 
            fc = calFc_fsQtcQts(app.state);
            app.state.fc = fc;
            app.FcHzEditField.Value = fc;
            
            [Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd, ~] = calBoxRLC_BoxParam(app.state);
            setBoxRLC(app,Cab, Rab, Mab, vol_Rmd, Va_Rmd, boxDepth_Rmd, Vm_Rmd);
            showBoxSize(app);
            showBoxParam(app);
        end

        % Value changed function: InputpowerWEditField
        function InputpowerWEditFieldValueChanged(app, event)
            value = app.InputpowerWEditField.Value;
            Eg = sqrt(2 * app.state.elec.Re * value); % v = sqrt( input power x R x 2)
            app.state.est_xmax = EstXmax_fromTSEgVab(app.state, Eg, app.state.box.Vab);
            app.estxmaxcmEditField.Value = app.state.est_xmax;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_2 = uipanel(app.UIFigure);
            app.ElectricParametersPanel = uipanel(app.Panel_2);
            app.ElectricParametersPanel.Title = 'Electric Parameters';
            app.ReohmsEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.ReohmsEditFieldLabel.Text = 'Re (ohms)';
            app.ReohmsEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.ReohmsEditField.ValueChangedFcn = createCallbackFcn(app, @ReohmsEditFieldValueChanged, true);
            app.LeHEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.LeHEditFieldLabel.Text = 'Le (H)';
            app.LeHEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.LeHEditField.ValueChangedFcn = createCallbackFcn(app, @LeHEditFieldValueChanged, true);
            app.BlNAEditFieldLabel = uilabel(app.ElectricParametersPanel);
            app.BlNAEditFieldLabel.Text = 'Bl (N/A)';
            app.BlNAEditField = uieditfield(app.ElectricParametersPanel, 'numeric');
            app.BlNAEditField.ValueChangedFcn = createCallbackFcn(app, @BlNAEditFieldValueChanged, true);
            app.ExportButton_2 = uibutton(app.Panel_2, 'push');
            app.ExportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportButton_2Pushed, true);
            app.ExportButton_2.Text = 'Export';
            app.ReloadButton_4 = uibutton(app.Panel_2, 'push');
            app.ReloadButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReloadButton_4Pushed, true);
            app.ReloadButton_4.Text = 'Reload';
            app.MechanicalParametersPanel = uipanel(app.Panel_2);
            app.MechanicalParametersPanel.Title = 'Mechanical Parameters';
            app.mmkgEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.mmkgEditFieldLabel.Text = 'mm (kg)';
            app.mmkgEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.mmkgEditField.ValueChangedFcn = createCallbackFcn(app, @mmkgEditFieldValueChanged, true);
            app.cmmNEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.cmmNEditFieldLabel.Text = 'cm (m/N)';
            app.cmmNEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.cmmNEditField.ValueChangedFcn = createCallbackFcn(app, @cmmNEditFieldValueChanged, true);
            app.rmkgsEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.rmkgsEditFieldLabel.Text = 'rm (kg/s)';
            app.rmkgsEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.rmkgsEditField.ValueChangedFcn = createCallbackFcn(app, @rmkgsEditFieldValueChanged, true);
            app.xmaxcmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.xmaxcmEditFieldLabel.Text = 'xmax (cm)';
            app.xmaxcmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.xmaxcmEditField.ValueChangedFcn = createCallbackFcn(app, @xmaxcmEditFieldValueChanged, true);
            app.outrmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.outrmEditFieldLabel.Text = 'out r (m)';
            app.outrmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.outrmEditField.ValueChangedFcn = createCallbackFcn(app, @outrmEditFieldValueChanged, true);
            app.inrmEditFieldLabel = uilabel(app.MechanicalParametersPanel);
            app.inrmEditFieldLabel.Text = 'in r (m)';
            app.inrmEditField = uieditfield(app.MechanicalParametersPanel, 'numeric');
            app.inrmEditField.ValueChangedFcn = createCallbackFcn(app, @inrmEditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.ThieleSmallParametersPanel_2 = uipanel(app.Panel);
            app.ThieleSmallParametersPanel_2.Title = 'Thiele-Small Parameters';
            app.ReEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.ReEditFieldLabel.Text = 'Re';
            app.ReEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.ReEditField.ValueChangedFcn = createCallbackFcn(app, @ReEditFieldValueChanged, true);
            app.QesEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.QesEditFieldLabel.Text = 'Qes';
            app.QesEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.QesEditField.ValueChangedFcn = createCallbackFcn(app, @QesEditFieldValueChanged, true);
            app.QmsEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.QmsEditFieldLabel.Text = 'Qms';
            app.QmsEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.QmsEditField.ValueChangedFcn = createCallbackFcn(app, @QmsEditFieldValueChanged, true);
            app.fsHzEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.fsHzEditFieldLabel.Text = 'fs (Hz)';
            app.fsHzEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.fsHzEditField.ValueChangedFcn = createCallbackFcn(app, @fsHzEditFieldValueChanged, true);
            app.Sdm2EditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.Sdm2EditFieldLabel.Text = 'Sd (m^2)';
            app.Sdm2EditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.Sdm2EditField.ValueChangedFcn = createCallbackFcn(app, @Sdm2EditFieldValueChanged, true);
            app.VasLEditFieldLabel = uilabel(app.ThieleSmallParametersPanel_2);
            app.VasLEditFieldLabel.Text = 'Vas (L)';
            app.VasLEditField = uieditfield(app.ThieleSmallParametersPanel_2, 'numeric');
            app.VasLEditField.ValueChangedFcn = createCallbackFcn(app, @VasLEditFieldValueChanged, true);
            app.TSinterpretedparamPanel = uipanel(app.Panel);
            app.TSinterpretedparamPanel.Title = 'TS interpreted param';
            app.pifs2Qtss1EditFieldLabel = uilabel(app.TSinterpretedparamPanel);
            app.pifs2Qtss1EditFieldLabel.Text = '2 pi fs/ 2Qts (s^-1)';
            app.pifs2Qtss1EditField = uieditfield(app.TSinterpretedparamPanel, 'numeric');
            app.pifs2Qtss1EditField.ValueChangedFcn = createCallbackFcn(app, @pifs2Qtss1EditFieldValueChanged, true);
            app.Decayrate92Label = uilabel(app.TSinterpretedparamPanel);
            app.Decayrate92Label.Text = 'Decay rate (>92)';
            app.QtsEditFieldLabel = uilabel(app.TSinterpretedparamPanel);
            app.QtsEditFieldLabel.Text = 'Qts';
            app.QtsEditField = uieditfield(app.TSinterpretedparamPanel, 'numeric');
            app.QtsEditField.ValueChangedFcn = createCallbackFcn(app, @QtsEditFieldValueChanged, true);
            app.Panel_3 = uipanel(app.UIFigure);
            app.SpeakerPortsPanel = uipanel(app.Panel_3);
            app.SpeakerPortsPanel.Title = 'Speaker Ports';
            app.numportsEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.numportsEditFieldLabel.Text = 'num ports';
            app.numportsEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.numportsEditField.ValueChangedFcn = createCallbackFcn(app, @numportsEditFieldValueChanged, true);
            app.portradiusmEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.portradiusmEditFieldLabel.Text = 'port radius (m)';
            app.portradiusmEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.portradiusmEditField.ValueChangedFcn = createCallbackFcn(app, @portradiusmEditFieldValueChanged, true);
            app.portlengthmEditFieldLabel = uilabel(app.SpeakerPortsPanel);
            app.portlengthmEditFieldLabel.Text = 'port length (m)';
            app.portlengthmEditField = uieditfield(app.SpeakerPortsPanel, 'numeric');
            app.portlengthmEditField.ValueChangedFcn = createCallbackFcn(app, @portlengthmEditFieldValueChanged, true);
            app.SpeakerEnclosurePanel = uipanel(app.Panel_3);
            app.SpeakerEnclosurePanel.Title = 'Speaker Enclosure ';
            app.Volumeboxm3Label = uilabel(app.SpeakerEnclosurePanel);
            app.Volumeboxm3Label.Text = 'Volume box (m^3)';
            app.Volumeboxm3EditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.Volumeboxm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumeboxm3EditFieldValueChanged, true);
            app.backaream2Label = uilabel(app.SpeakerEnclosurePanel);
            app.backaream2Label.Text = 'back area (m^2)';
            app.backaream2EditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.backaream2EditField.ValueChangedFcn = createCallbackFcn(app, @backaream2EditFieldValueChanged, true);
            app.boxdepthmEditFieldLabel = uilabel(app.SpeakerEnclosurePanel);
            app.boxdepthmEditFieldLabel.Text = 'box depth (m)';
            app.boxdepthmEditField = uieditfield(app.SpeakerEnclosurePanel, 'numeric');
            app.boxdepthmEditField.ValueChangedFcn = createCallbackFcn(app, @boxdepthmEditFieldValueChanged, true);
            app.EnclosureInterpretedparamPanel = uipanel(app.Panel_3);
            app.EnclosureInterpretedparamPanel.Title = 'Enclosure Interpreted param';
            app.FcHzEditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.FcHzEditFieldLabel.Text = 'Fc (Hz)';
            app.FcHzEditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.FcHzEditField.ValueChangedFcn = createCallbackFcn(app, @FcHzEditFieldValueChanged, true);
            app.QtcEditField_2Label = uilabel(app.EnclosureInterpretedparamPanel);
            app.QtcEditField_2Label.Text = 'Qtc';
            app.QtcEditField_2 = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.QtcEditField_2.ValueChangedFcn = createCallbackFcn(app, @QtcEditField_2ValueChanged, true);
            app.EffVolumem3EditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.EffVolumem3EditFieldLabel.Text = 'Eff Volume (m^3)';
            app.EffVolumem3EditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.EffVolumem3EditField.ValueChangedFcn = createCallbackFcn(app, @EffVolumem3EditFieldValueChanged2, true);
            app.FirstmodefreqEditFieldLabel = uilabel(app.EnclosureInterpretedparamPanel);
            app.FirstmodefreqEditFieldLabel.Text = 'First mode freq';
            app.FirstmodefreqEditField = uieditfield(app.EnclosureInterpretedparamPanel, 'numeric');
            app.FirstmodefreqEditField.ValueChangedFcn = createCallbackFcn(app, @FirstmodefreqEditFieldValueChanged, true);
            app.powerPanel = uipanel(app.Panel_3);
            app.powerPanel.Title = 'power';
            app.estxmaxcmEditFieldLabel = uilabel(app.powerPanel);
            app.estxmaxcmEditFieldLabel.Text = 'est xmax (cm)';
            app.estxmaxcmEditField = uieditfield(app.powerPanel, 'numeric');
            app.InputpowerWEditFieldLabel = uilabel(app.powerPanel);
            app.InputpowerWEditFieldLabel.Text = 'Input power (W)';
            app.InputpowerWEditField = uieditfield(app.powerPanel, 'numeric');
            app.InputpowerWEditField.ValueChangedFcn = createCallbackFcn(app, @InputpowerWEditFieldValueChanged, true);
            app.AcousticmaterialPanel = uipanel(app.Panel_3);
            app.AcousticmaterialPanel.Title = 'Acoustic material';
            app.Volumematerialm3EditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.Volumematerialm3EditFieldLabel.Text = 'Volume material (m^3)';
            app.Volumematerialm3EditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.Volumematerialm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumematerialm3EditFieldValueChanged, true);
            app.materialRfraylmmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.materialRfraylmmEditFieldLabel.Text = 'material Rf (rayl/mm)';
            app.materialRfraylmmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.materialRfraylmmEditField.ValueChangedFcn = createCallbackFcn(app, @materialRfraylmmEditFieldValueChanged, true);
            app.VolratioVaVmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.VolratioVaVmEditFieldLabel.Text = 'Vol ratio (Va/Vm)';
            app.VolratioVaVmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.VolratioVaVmEditField.ValueChangedFcn = createCallbackFcn(app, @VolratioVaVmEditFieldValueChanged, true);
            app.Volumeairm3EditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.Volumeairm3EditFieldLabel.Text = 'Volume air (m^3)';
            app.Volumeairm3EditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.Volumeairm3EditField.ValueChangedFcn = createCallbackFcn(app, @Volumeairm3EditFieldValueChanged, true);
            app.materialticknessmmEditFieldLabel = uilabel(app.AcousticmaterialPanel);
            app.materialticknessmmEditFieldLabel.Text = 'material tickness (mm)';
            app.materialticknessmmEditField = uieditfield(app.AcousticmaterialPanel, 'numeric');
            app.materialticknessmmEditField.ValueChangedFcn = createCallbackFcn(app, @materialticknessmmEditFieldValueChanged, true);
            app.HelpButton = uibutton(app.Panel_3, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.GeneralpropertiesPanel = uipanel(app.Panel_3);
            app.GeneralpropertiesPanel.Title = 'General properties';
            app.cmsEditFieldLabel = uilabel(app.GeneralpropertiesPanel);
            app.cmsEditFieldLabel.Text = 'c (m/s)';
            app.cmsEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.cmsEditField.ValueChangedFcn = createCallbackFcn(app, @cmsEditFieldValueChanged, true);
            app.gammaLabel = uilabel(app.GeneralpropertiesPanel);
            app.gammaLabel.Text = 'gamma ';
            app.gammaEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @gammaEditFieldValueChanged, true);
            app.PaLabel = uilabel(app.GeneralpropertiesPanel);
            app.PaLabel.Text = 'Pa ';
            app.PaEditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.PaEditField.ValueChangedFcn = createCallbackFcn(app, @PaEditFieldValueChanged, true);
            app.rhokgm3EditFieldLabel = uilabel(app.GeneralpropertiesPanel);
            app.rhokgm3EditFieldLabel.Text = 'rho (kg/m^3)';
            app.rhokgm3EditField = uieditfield(app.GeneralpropertiesPanel, 'numeric');
            app.rhokgm3EditField.ValueChangedFcn = createCallbackFcn(app, @rhokgm3EditFieldValueChanged, true);
            app.RecommendationPanel = uipanel(app.Panel_3);
            app.RecommendationPanel.Title = 'Recommendation';
            app.volumeairRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.volumeairRmdEditFieldLabel.Text = 'volume air Rmd';
            app.volumeairRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.VolboxRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.VolboxRmdEditFieldLabel.Text = 'Vol box Rmd';
            app.VolboxRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.volmatRmdEditFieldLabel = uilabel(app.RecommendationPanel);
            app.volmatRmdEditFieldLabel.Text = 'vol mat Rmd';
            app.volmatRmdEditField = uieditfield(app.RecommendationPanel, 'numeric');
            app.Panel_5 = uipanel(app.UIFigure);
            app.UIAxes_4 = uiaxes(app.Panel_5);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            app.NonlinearityforBPanel = uipanel(app.Panel_5);
            app.NonlinearityforBPanel.Title = 'Nonlinearity for B';
            app.AdjustgraphButton = uibutton(app.NonlinearityforBPanel, 'push');
            app.AdjustgraphButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustgraphButtonPushed, true);
            app.AdjustgraphButton.Text = 'Adjust graph';
            app.coef_X2EditFieldLabel = uilabel(app.NonlinearityforBPanel);
            app.coef_X2EditFieldLabel.Text = 'coef_X2';
            app.coef_X2EditField = uieditfield(app.NonlinearityforBPanel, 'numeric');
            app.coef_X2EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditFieldValueChanged, true);
            app.coef_X1EditFieldLabel = uilabel(app.NonlinearityforBPanel);
            app.coef_X1EditFieldLabel.Text = 'coef_X1';
            app.coef_X1EditField = uieditfield(app.NonlinearityforBPanel, 'numeric');
            app.coef_X1EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditFieldValueChanged, true);
            app.coef_X0EditFieldLabel = uilabel(app.NonlinearityforBPanel);
            app.coef_X0EditFieldLabel.Text = 'coef_X0';
            app.coef_X0EditField = uieditfield(app.NonlinearityforBPanel, 'numeric');
            app.coef_X0EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditFieldValueChanged, true);
            app.coef_X3EditFieldLabel = uilabel(app.NonlinearityforBPanel);
            app.coef_X3EditFieldLabel.Text = 'coef_X3';
            app.coef_X3EditField = uieditfield(app.NonlinearityforBPanel, 'numeric');
            app.coef_X3EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditFieldValueChanged, true);
            app.coef_X4EditFieldLabel = uilabel(app.NonlinearityforBPanel);
            app.coef_X4EditFieldLabel.Text = 'coef_X4';
            app.coef_X4EditField = uieditfield(app.NonlinearityforBPanel, 'numeric');
            app.coef_X4EditField.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditFieldValueChanged, true);
            app.ReloadButton = uibutton(app.NonlinearityforBPanel, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.Panel_6 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_6);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.NonlinearityforLPanel = uipanel(app.Panel_6);
            app.NonlinearityforLPanel.Title = 'Nonlinearity for L';
            app.AdjustgraphButton_2 = uibutton(app.NonlinearityforLPanel, 'push');
            app.AdjustgraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @AdjustgraphButton_2Pushed, true);
            app.AdjustgraphButton_2.Text = 'Adjust graph';
            app.coef_X2EditField_2Label = uilabel(app.NonlinearityforLPanel);
            app.coef_X2EditField_2Label.Text = 'coef_X2';
            app.coef_X2EditField_2 = uieditfield(app.NonlinearityforLPanel, 'numeric');
            app.coef_X2EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditField_2ValueChanged, true);
            app.coef_X1EditField_2Label = uilabel(app.NonlinearityforLPanel);
            app.coef_X1EditField_2Label.Text = 'coef_X1';
            app.coef_X1EditField_2 = uieditfield(app.NonlinearityforLPanel, 'numeric');
            app.coef_X1EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditField_2ValueChanged, true);
            app.coef_X0EditField_2Label = uilabel(app.NonlinearityforLPanel);
            app.coef_X0EditField_2Label.Text = 'coef_X0';
            app.coef_X0EditField_2 = uieditfield(app.NonlinearityforLPanel, 'numeric');
            app.coef_X0EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditField_2ValueChanged, true);
            app.coef_X3EditField_2Label = uilabel(app.NonlinearityforLPanel);
            app.coef_X3EditField_2Label.Text = 'coef_X3';
            app.coef_X3EditField_2 = uieditfield(app.NonlinearityforLPanel, 'numeric');
            app.coef_X3EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditField_2ValueChanged, true);
            app.coef_X4EditField_2Label = uilabel(app.NonlinearityforLPanel);
            app.coef_X4EditField_2Label.Text = 'coef_X4';
            app.coef_X4EditField_2 = uieditfield(app.NonlinearityforLPanel, 'numeric');
            app.coef_X4EditField_2.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditField_2ValueChanged, true);
            app.ReloadButton_2 = uibutton(app.NonlinearityforLPanel, 'push');
            app.ReloadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReloadButton_2Pushed, true);
            app.ReloadButton_2.Text = 'Reload';
            app.Panel_7 = uipanel(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.Panel_7);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.NonlinearityforKPanel = uipanel(app.Panel_7);
            app.NonlinearityforKPanel.Title = 'Nonlinearity for K';
            app.AdjustgraphButton_3 = uibutton(app.NonlinearityforKPanel, 'push');
            app.AdjustgraphButton_3.ButtonPushedFcn = createCallbackFcn(app, @AdjustgraphButton_3Pushed, true);
            app.AdjustgraphButton_3.Text = 'Adjust graph';
            app.coef_X2EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X2EditField_3Label.Text = 'coef_X2';
            app.coef_X2EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X2EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X2EditField_3ValueChanged, true);
            app.coef_X1EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X1EditField_3Label.Text = 'coef_X1';
            app.coef_X1EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X1EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X1EditField_3ValueChanged, true);
            app.coef_X0EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X0EditField_3Label.Text = 'coef_X0';
            app.coef_X0EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X0EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X0EditField_3ValueChanged, true);
            app.coef_X3EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X3EditField_3Label.Text = 'coef_X3';
            app.coef_X3EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X3EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X3EditField_3ValueChanged, true);
            app.coef_X4EditField_3Label = uilabel(app.NonlinearityforKPanel);
            app.coef_X4EditField_3Label.Text = 'coef_X4';
            app.coef_X4EditField_3 = uieditfield(app.NonlinearityforKPanel, 'numeric');
            app.coef_X4EditField_3.ValueChangedFcn = createCallbackFcn(app, @coef_X4EditField_3ValueChanged, true);
            app.ReloadButton_3 = uibutton(app.NonlinearityforKPanel, 'push');
            app.ReloadButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReloadButton_3Pushed, true);
            app.ReloadButton_3.Text = 'Reload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thieleSmallCalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end